classdef dashboard_160 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Play                 matlab.ui.control.Button
        Save                 matlab.ui.control.Button
        Stop                 matlab.ui.control.Button
        poseLabel            matlab.ui.control.Label
        xLabel               matlab.ui.control.Label
        yLabel               matlab.ui.control.Label
        goalLabel            matlab.ui.control.Label
        UINavigation         matlab.ui.control.UIAxes
        ONOFFEditFieldLabel  matlab.ui.control.Label
        onoff                matlab.ui.control.NumericEditField
        poseX                matlab.ui.control.NumericEditField
        poseY                matlab.ui.control.NumericEditField
        goalX                matlab.ui.control.NumericEditField
        goalY                matlab.ui.control.NumericEditField
        state                matlab.ui.control.Label
        stateLabel           matlab.ui.control.Label
        UIMap                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        youbot
    end


    methods (Access = private)

        % Button pushed function: Play
        function PlayButtonPushed(app, event)
            app.onoff.Value = 1;
            app.youbot = Youbot(app, 10); % open explorer with resolution of 1/10 m
            app.youbot = app.youbot.exploration(); % start explorer
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            app.youbot.save();
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            app.onoff.Value = 0;
            app.youbot.stop();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Play = uibutton(app.UIFigure, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.Play.Icon = 'icon-play.png';
            app.Play.Text = '';
            app.Save = uibutton(app.UIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Icon = 'icon-save.png';
            app.Save.Text = '';
            app.Stop = uibutton(app.UIFigure, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Icon = 'icon-stop.png';
            app.Stop.Text = '';
            app.poseLabel = uilabel(app.UIFigure);
            app.poseLabel.Text = 'pose';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y';
            app.goalLabel = uilabel(app.UIFigure);
            app.goalLabel.Text = 'goal';
            app.UINavigation = uiaxes(app.UIFigure);
            title(app.UINavigation, 'Navigation')
            xlabel(app.UINavigation, 'X')
            ylabel(app.UINavigation, 'Y')
            app.UINavigation.XLim = [0 160];
            app.UINavigation.XLimMode = 'manual';
            app.UINavigation.YLim = [0 160];
            app.UINavigation.YLimMode = 'manual';
            app.UINavigation.CLim = [0 160];
            app.UINavigation.CLimMode = 'manual';
            app.UINavigation.XTick = [0 40 80 120 160];
            app.UINavigation.YTick = [0 40 80 120 160];
            app.ONOFFEditFieldLabel = uilabel(app.UIFigure);
            app.ONOFFEditFieldLabel.Text = 'ON/OFF';
            app.onoff = uieditfield(app.UIFigure, 'numeric');
            app.onoff.Limits = [0 1];
            app.onoff.ValueDisplayFormat = '%.0f';
            app.poseX = uieditfield(app.UIFigure, 'numeric');
            app.poseX.ValueDisplayFormat = '%11.2g';
            app.poseY = uieditfield(app.UIFigure, 'numeric');
            app.poseY.ValueDisplayFormat = '%11.2g';
            app.goalX = uieditfield(app.UIFigure, 'numeric');
            app.goalX.ValueDisplayFormat = '%11.2g';
            app.goalY = uieditfield(app.UIFigure, 'numeric');
            app.goalY.ValueDisplayFormat = '%11.2g';
            app.state = uilabel(app.UIFigure);
            app.state.Text = '.';
            app.stateLabel = uilabel(app.UIFigure);
            app.stateLabel.Text = 'state';
            app.UIMap = uiaxes(app.UIFigure);
            title(app.UIMap, 'Map')
            xlabel(app.UIMap, 'X')
            ylabel(app.UIMap, 'Y')
            app.UIMap.XLim = [0 160];
            app.UIMap.XLimMode = 'manual';
            app.UIMap.YLim = [0 160];
            app.UIMap.YLimMode = 'manual';
            app.UIMap.CLim = [0 160];
            app.UIMap.CLimMode = 'manual';
            app.UIMap.XTick = [0 40 80 120 160];
            app.UIMap.YTick = [0 40 80 120 160];
        end
    end

    methods (Access = public)

        % Construct app
        function app = dashboard_160

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end